classdef CEAM_app_eng_th_Michal_Skiba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CEAMichalSkibaUIFigure          matlab.ui.Figure
        CloseallplotsButton             matlab.ui.control.Button
        Gravitational_acceleration_Spinner  matlab.ui.control.Spinner
        Gravitationalaccelerationms2Label  matlab.ui.control.Label
        Catalyst_bed_diameter_Spinner   matlab.ui.control.Spinner
        CatalystbeddiametermmSpinnerLabel  matlab.ui.control.Label
        Engine_thrust_Spinner           matlab.ui.control.Spinner
        EnginethrustNSpinnerLabel       matlab.ui.control.Label
        MonopropelantenginecalculationsLabel  matlab.ui.control.Label
        MonopropelantenginecalculationsONOFF  matlab.ui.control.CheckBox
        Area_ratio_Plot                 matlab.ui.control.CheckBox
        Design_pressure                 matlab.ui.control.Spinner
        OxidaizerconcentrationLabel_2   matlab.ui.control.Label
        pressure_ratio_design_pressureLabel  matlab.ui.control.Label
        pressure_ratio_design_pressure  matlab.ui.control.CheckBox
        isp_vac_Plot                    matlab.ui.control.CheckBox
        Reactant_2_3_Temperature        matlab.ui.control.Spinner
        Reactant23TemperatureKLabel     matlab.ui.control.Label
        Reactant_1_Temperature          matlab.ui.control.Spinner
        FuelTemperatureKLabel           matlab.ui.control.Label
        ClickstarttostartcalculationsLabel  matlab.ui.control.Label
        ChooseplotstobeshownLabel       matlab.ui.control.Label
        Reactant3OnOffLabel             matlab.ui.control.Label
        Reactant_3_on_off               matlab.ui.control.CheckBox
        Pressure_ratio_min              matlab.ui.control.Spinner
        PressureratioPressurebeforenozzleexhauspressureLabel  matlab.ui.control.Label
        Pressure_ratio_Max              matlab.ui.control.Spinner
        Pressure_ratio_step             matlab.ui.control.Spinner
        MaximumLabel_2                  matlab.ui.control.Label
        NumberofstepsLabel              matlab.ui.control.Label
        minimumLabel_2                  matlab.ui.control.Label
        Reactant_1_to_rest_Max          matlab.ui.control.Spinner
        Reactant_1_to_rest_step         matlab.ui.control.Spinner
        Reactant_1_to_rest_min          matlab.ui.control.Spinner
        Reactant1torestLabel            matlab.ui.control.Label
        CCP_Max                         matlab.ui.control.Spinner
        CCP_step                        matlab.ui.control.Spinner
        CCP_min                         matlab.ui.control.Spinner
        PressurebeforenozzlebarLabel    matlab.ui.control.Label
        MaximumLabel                    matlab.ui.control.Label
        StepsizeLabel                   matlab.ui.control.Label
        minimumLabel                    matlab.ui.control.Label
        Gamma_Plot                      matlab.ui.control.CheckBox
        Cp_Plot                         matlab.ui.control.CheckBox
        Reactant_3_Name                 matlab.ui.control.EditField
        Reactan3Label                   matlab.ui.control.Label
        Reactant_2_Name                 matlab.ui.control.EditField
        Reactant2Label                  matlab.ui.control.Label
        Reactant_1_Name                 matlab.ui.control.EditField
        Reactant1Label                  matlab.ui.control.Label
        Reactant_3_concentration        matlab.ui.control.Spinner
        OxidaizerconcentrationLabel     matlab.ui.control.Label
        Cstar_Plot                      matlab.ui.control.CheckBox
        Cf_Plot                         matlab.ui.control.CheckBox
        isp_Plot                        matlab.ui.control.CheckBox
        CCT_Plot                        matlab.ui.control.CheckBox
        StartButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
 evalin('base', 'clear')
 Loading_msg=msgbox('Loading-Calculations have started and soon plots will show up','Loading');

%PROBLEM-combustion of liquid ammonia and hydrogen peroxide

%{
ASSUMED:
-infinte chamber lenght

%}
%%
%GIVEN 
%close all %% MG ZMIANA
ccp_max=app.CCP_Max.Value; %maximum combustion chamber pressure [bar]
ccp_step=app.CCP_step.Value; %combustion chamber pressure step between max and min in calculations  [bar]
ccp_min=app.CCP_min.Value; %minimum combustion chamber pressure [bar]
ccp=ccp_min:ccp_step:ccp_max;
%{
pa_max=1; %maximum atmospheric pressur [bar]
pa_nstep=10; %number of steps of atmospheric pressur between max and min in calculations  [bar]
pa_min=0.01; %minimum atmospheric pressur [bar]
pa=logspace(log10(pa_min),log10(pa_max),pa_nstep);
%}
if app.pressure_ratio_design_pressure.Value == false
        pr_max=app.Pressure_ratio_Max.Value; %maximum pressure ratio chamber pressure/exhaus pressure [-]
        pr_nstep=app.Pressure_ratio_step.Value; %number of steps of pressure ratio chamber pressure/exhaus pressure between max and min in calculations  [bar]
        pr_min=app.Pressure_ratio_min.Value; %minimum pressure ratio chamber pressure/exhaus pressure [-]
        pr=logspace(log10(pr_min),log10(pr_max),pr_nstep); 
            
            else
        pr=ccp/app.Design_pressure.Value;        
             end


T_a=app.Reactant_1_Temperature.Value; %Liquid ammonia inlet temperature -value foced by CEA [k]
T_hp=app.Reactant_2_3_Temperature.Value; %hydrogen peroxide inlet temperature -must be bigger than 272.74 [k]
T_w=T_hp; %Water inlet temperature the same as hydrogen peroxide temperature -must be bigger than 273.15 [k]

%Rc=app.Reactant_1_to_rest_min.Value:app.Reactant_1_to_rest_step.Value:app.Reactant_1_to_rest_Max.Value;

hpc_max=app.Reactant_1_to_rest_Max.Value; %maximum hydrogen peroxide concentration [%]
hpc_step=app.Reactant_1_to_rest_step.Value; %hydrogen peroxide concentration step between max and min in calculations  [%] 
hpc_min=app.Reactant_1_to_rest_min.Value; %minimum hydrogen peroxide concentration [%]
hpc=hpc_min:hpc_step:hpc_max;

    if app.Reactant_3_on_off.Value == true
%%
%CEAM CALCULATIONS
i=1;
for h=hpc

    R1=h;
    R2=((100.0-h)/(1+(app.Reactant_3_concentration.Value/100)/(1-app.Reactant_3_concentration.Value/100)));
    R3=(app.Reactant_3_concentration.Value/100)*R2/(1-app.Reactant_3_concentration.Value/100);
    
cea(i)=CEA('problem','rocket',... %Problem type-Rocket
        'equilibrium',...%equilibrium flow.  This means that the product composition is allowed to change such that the Gibbs free energy is minimized at every nozzle station.  
        'case','Ammonia and hydrogen peroxide',...%The case name is a file name printed in the output.  
        'p(bar)',ccp,...%Combustion chamber pressure [bar]
        'pi/p',pr,...%pressure ratio chamber pressure/exhaus pressure [-]
        'reactants',...
        'name',app.Reactant_1_Name.Value,'wt%',R1,'t(k)',T_a,...%Liquid ammonia is used as fuel temperature is foced by CEA other values are taken from CEA library
        'name',app.Reactant_2_Name.Value,'wt%',R2,'t(k)',T_hp,...%hydrogen peroxide is used as oxidant thined with water temperature of 280K was assumed other values are taken from CEA library 
        'name',app.Reactant_3_Name.Value,'wt%',R3,'t(k)',T_w,...%Water to decrease concentrtion of hydrogen peroxide temperature of 280K the same as hydrogen peroxide other values are taken from CEA library 
        'output','mks','trac',1e-15,...%mks sets allmost SI units; Trace can be used to tighten the convergence value requirement to enhance accuracy for trace product species. 
        'end', 'results');
i=i+1;
end
    else
hpc_max=app.Reactant_1_to_rest_Max.Value; %maximum hydrogen peroxide concentration [%]
hpc_step=app.Reactant_1_to_rest_step.Value; %hydrogen peroxide concentration step between max and min in calculations  [%] 
hpc_min=app.Reactant_1_to_rest_min.Value; %minimum hydrogen peroxide concentration [%]
hpc=hpc_min:hpc_step:hpc_max;
%%
%CEAM CALCULATIONS
i=1;
for h=hpc
cea(i)=CEA('problem','rocket',... %Problem type-Rocket
        'equilibrium',...%equilibrium flow.  This means that the product composition is allowed to change such that the Gibbs free energy is minimized at every nozzle station.  
        'case','Ammonia and hydrogen peroxide',...%The case name is a file name printed in the output.  
        'p(bar)',ccp,...%Combustion chamber pressure [bar]
        'pi/p',pr,...%pressure ratio chamber pressure/exhaus pressure [-]
        'reactants',...
        'name',app.Reactant_1_Name.Value,'wt%',h,'t(k)',T_a,...%Liquid ammonia is used as fuel temperature is foced by CEA other values are taken from CEA library 
        'name',app.Reactant_2_Name.Value,'wt%',(100.0-h),'t(k)',T_hp,...%hydrogen peroxide is used as oxidant thined with water temperature of 280K was assumed other values are taken from CEA library 
        'output','mks','trac',1e-15,...%mks sets allmost SI units; Trace can be used to tighten the convergence value requirement to enhance accuracy for trace product species. 
        'end', 'results'); 
i=i+1;
end
    end
    

assignin('base','CEAM_app_output',cea);

%% Monopropelant engine calculations
if app.MonopropelantenginecalculationsONOFF.Value ==true

grav=app.Gravitational_acceleration_Spinner.Value; %Gravitational acceleration [m/s^2]
Thrust=app.Engine_thrust_Spinner.Value; %Design thrust [N]
d_cb=app.Catalyst_bed_diameter_Spinner.Value; %Design diameter of the catalyst bed [mm]


% E1. Plot- Flow rate-----------------------------------------------------------------------------
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Mass flow of propellant rate vs  Rectant 1 concentration','NumberTitle','off')
FlowratePlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav)

% E2. Plot- Loading factor------------------------------------------------------------------------
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Loading factor vs  Rectant 1 concentration','NumberTitle','off')
LoadingfactorPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

% E3. Plot- Throat Area-------------------------------------------------------------------------------- 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Throat Area vs  Rectant 1 concentration','NumberTitle','off')
ThroatAreaPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)


% E4. Plot- Throat diameter-------------------------------------------------------------------------------- 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Throat Diameter vs  Rectant 1 concentration','NumberTitle','off')
ThroatDiameterPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)


% E5. Plot- Throat Area 2.0-------------------------------------------------------------------------------- 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Throat Area 2.0 vs  Rectant 1 concentration','NumberTitle','off')
ThroatArea2Plot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

% E6. Plot- Needle injector length-------------------------------------------------------------------------------- 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Needle injector length vs  Rectant 1 concentration','NumberTitle','off')
NeedleInjectorPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

% E7. Plot- Needle injector pressure drop-------------------------------------------------------------------------------- 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Needle injector pressure drop vs  Rectant 1 concentration','NumberTitle','off')
NeedleInjectorpressuredropPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

% E8. Plot- Needle injector pressure drop correct-------------------------------------------------------------------------------- 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 
NL=[1 1 1]; %...
%     2 2 2;...
%     3 3 3;...
%     4 4 4;...
%     5 5 5;...
%     1 2 3;...
%     1.5 2.5 3.5;...
%     2 3 4;...
%     3 4 5;...
%     4 5 6;...
%     5 6 7];
Table_pressure_drop= ["Needle 1" "Needle 2" "Needle 3" "Pressure" "concentration" "Pressure drop"];
assignin('base','Table_pressure_drop',Table_pressure_drop);
for n=1:size(NL,1)
figure('Name','Needle injector pressure drop vs  Rectant 1 concentration','NumberTitle','off')
NeedleInjectorpressuredrop2Plot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb, NL(n, 1), NL(n, 2), NL(n, 3), n+1)
end
 
% E9. Plot- Wall thickness -------------------------------------------------------------------------------- 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

 figure('Name','Wall thickness vs  Rectant 1 concentration','NumberTitle','off')
 WallThicknessPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

 
Table_wall_thickness= ["Concentration" "Pressure" "Reaction chamber" "Nozzle Converging up" "Nozzle Converging down" "Throat" "Nozzle divergent up" "Nozzle divergent exit" "Top cover"];
assignin('base','Table_wall_thickness',Table_wall_thickness);

 % E10. Plot- Heat separator -------------------------------------------------------------------------------- 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

 figure('Name','Heat separator length vs  Rectant 1 concentration','NumberTitle','off')
 HeatSeparatorPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

% E11. Plot- Propellant Feed tube -------------------------------------------------------------------------------- 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

 figure('Name','Propellant feed tube pressure drop vs  Rectant 1 concentration','NumberTitle','off')
PFTdpPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

 
Table_PFT_dp= ["Concentration" "Chamber Pressure" "Pressure drop"];
assignin('base','Table_Propellant_feed_tube_pressure_drop',Table_PFT_dp);

 
end

Table_nozzle=["Concentration" "Pressure" "Throat diameter" "Nozzle exit diameter" "Area ratio"];
assignin('base','Nozzle_parameters',Table_nozzle);


%PLOTS
%% Plot 1
if app.CCT_Plot.Value ==true
%Plot 1-Combustion chamber Temperature vs Oxidant to fuel ratio (for diferent hydrogen peroxide concentration) 
%plot is made for Combustion chamber pressure number "pn" as Combustion chamber Temperature dont change much with pressure 
pn=1; %position of Combustion chamber pressure in vector "ccp" 
figure('Name','Temperature before nozzle vs  Rectant 1 concentration','NumberTitle','off')
tccPlot(hpc, ccp, cea, pr, pn)
end

%% Plot 2
if app.isp_Plot.Value ==true
%Plot 2-Specific impulse vs Oxidant to fuel ratio (for different atmospheric pressure) 
%plot is made for hydrogen peroxide concentration number "hpcn" and for Combustion chamber pressure number "pn" as Combustion chamber Temperature dont change much with pressure 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Specific impulse vs  Rectant 1 concentration','NumberTitle','off')
ispPlot(hpc, ccp, cea, pr, pn, hpcn)
end
%% Plot 3
if app.isp_vac_Plot.Value ==true
%Plot 2-Specific impulse vs Oxidant to fuel ratio (for different atmospheric pressure) 
%plot is made for hydrogen peroxide concentration number "hpcn" and for Combustion chamber pressure number "pn" as Combustion chamber Temperature dont change much with pressure 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Vacuum Specific impulse vs  Rectant 1 concentration','NumberTitle','off')
ispvacPlot(hpc, ccp, cea, pr, pn, hpcn)
end

%% Plot 4
if app.Cf_Plot.Value ==true
%Plot 3-Cf vs Oxidant to fuel ratio (for different atmospheric pressure) 
%plot is made for hydrogen peroxide concentration number "hpcn" and for Combustion chamber pressure number "pn" as Combustion chamber Temperature dont change much with pressure 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Cf vs  Rectant 1 concentration','NumberTitle','off')
cfPlot(hpc, ccp, cea, pr, pn, hpcn)
end

%% Plot 5
if app.Cstar_Plot.Value ==true
%Plot 4-C* vs Oxidant to fuel ratio (for different atmospheric pressure) 
%plot is made for hydrogen peroxide concentration number "hpcn" and for Combustion chamber pressure number "pn" as Combustion chamber Temperature dont change much with pressure 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','C* vs  Rectant 1 concentration','NumberTitle','off')
cstarPlot(hpc, ccp, cea, pr, pn, hpcn)
end

%% Plot 6
if app.Cp_Plot.Value ==true
%Plot 5-Cp vs Oxidant to fuel ratio (for different atmospheric pressure) 
%plot is made for hydrogen peroxide concentration number "hpcn" and for Combustion chamber pressure number "pn" as Combustion chamber Temperature dont change much with pressure 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Cp vs  Rectant 1 concentration','NumberTitle','off')
cpPlot(hpc, ccp, cea, pr, pn, hpcn)
end

%% Plot 7
if app.Gamma_Plot.Value ==true
%Plot 6-γ vs Oxidant to fuel ratio (for different atmospheric pressure) 
%plot is made for hydrogen peroxide concentration number "hpcn" and for Combustion chamber pressure number "pn" as Combustion chamber Temperature dont change much with pressure 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','γ vs  Rectant 1 concentration','NumberTitle','off')
gammaPlot(hpc, ccp, cea, pr, pn, hpcn)
end

%% Plot 8
if app.Area_ratio_Plot.Value ==true
%Plot 6-γ vs Oxidant to fuel ratio (for different atmospheric pressure) 
%plot is made for hydrogen peroxide concentration number "hpcn" and for Combustion chamber pressure number "pn" as Combustion chamber Temperature dont change much with pressure 
hpcn=1; %position of hydrogen peroxide concentration in vector "hpc"
pn=1; %position of Combustion chamber pressure in vector "ccp" 

figure('Name','Area ratio vs  Rectant 1 concentration','NumberTitle','off')
AreaPlot(hpc, ccp, cea, pr, pn, hpcn)
end

delete(Loading_msg);
Done_msg=msgbox('Done');


%% PLOT FUNCTIONS

%% 1. Combustion chamber Temperature Plot function
function tccPlot(hpc, ccp, cea, pr, pn)
clf
ccp_size=size(ccp);
%pn = 1;
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2));%'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');

 hpc_size=size(hpc);
set(SliderCCP,'Callback',@(hObject,eventdata) tccPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value'))) )
end
%pn
ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Tcc [K]')
title({['Temperature before the nozzle vs ',app.Reactant_1_Name.Value, ' concentration']
    ['for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
i=1;
for h=hpc
    tcc(i)=cea(i).output.eql.temperature(pn,1);
i=i+1;
end

Table_CC_temperature(1,:) = tcc;
Table_CC_temperature(2,:) = hpc;
assignin('base','Table_CC_temperature',Table_CC_temperature);

hold on;
plot(hpc,tcc,'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
Tcc_i=spline(hpc,tcc,ofq1);

plot(ofq1,Tcc_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['hpc interpolated'])

lgd = legend;
legend('Location','eastoutside');
hold off
end
%% 2. isp Plot
function ispPlot(hpc, ccp, cea, pr, pn, hpcn)
clf
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value'))) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr
    ispm(i,j)=cea(i).output.eql.isp(pn,j);
    j=j+1;
    end
i=i+1;
end

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Isp [s]')
title({['Specific impulse vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,ispm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,ispm(:,i),ofq1);

plot(hpc,ispm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    
i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
   
 plot(hpc,ispm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,ispm(:,i),ofq1);

plot(hpc,ispm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    

             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end
%% 3. vacuum isp Plot
function ispvacPlot(hpc, ccp, cea, pr, pn, hpcn)
clf
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) ispvacPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr
    ispvm(i,j)=cea(i).output.eql.isp_vac(pn,j);
    j=j+1;
    end
i=i+1;
end

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Isp [s]')
title({['Vacuum Specific impulse vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on

%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
plot(hpc,ispvm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
ispv_i=spline(hpc,ispvm(:,i),ofq1);

plot(hpc,ispvm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,ispv_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])

i=i+1;
end
 
 else
      i=3;
 
     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value

plot(hpc,ispvm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

    
ofq1=hpc_min:hpc_step/10:hpc_max;
ispv_i=spline(hpc,ispvm(:,i),ofq1);

plot(hpc,ispvm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,ispv_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])


             end
i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end
%% 4. cf Plot
function cfPlot(hpc, ccp, cea, pr, pn, hpcn)
clf
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) cfPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn) )
end 
hpc_size=size(hpc);

% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) cfPlot(hpc, ccp, cea, of, of_min, of_max, of_step, pr, pn,  nearest(get(hObject,'Value'))) )
% end

i=1;
for h=hpc
    j=3;
    for g=pr
    cfm(i,j)=cea(i).output.eql.cf(pn,j);
    j=j+1;
    end
i=i+1;
end


ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Cf [-]')
title({['Cf vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on


%*****
 if app.pressure_ratio_design_pressure.Value == false
                
   i=3;
plot(hpc,cfm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,cfm(:,i),ofq1);

plot(hpc,cfm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'Pa interpolated'])

i=i+1;
end
 
 else
      i=3;
 
     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value

plot(hpc,cfm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

    
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,cfm(:,i),ofq1);

plot(hpc,cfm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'Pa interpolated'])


             end
i=i+1;
     end
          
end
%***

lgd = legend;
legend('Location','eastoutside');
hold off

end
%% 5. c* Plot
function cstarPlot(hpc, ccp, cea, pr, pn, hpcn)
clf
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 hpc_size=size(hpc);
set(SliderCCP,'Callback',@(hObject,eventdata) cstarPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn) )
end

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('C* [m/s]')
title({['C* vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';

i=1;
for h=hpc
    cstarm(i)=cea(i).output.eql.cstar(pn,1);
i=i+1;
end

hold on
i=1;
plot(hpc,cstarm,'o','MarkerEdgeColor','black','DisplayName','Calculated points')

    
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,cstarm,ofq1);

%plot(of,cea(i).output.eql.cstar(:,pn,1),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['hpc interpolated'])

lgd = legend;
legend('Location','eastoutside');
hold off

end
%% 6. cp Plot
function cpPlot(hpc, ccp, cea, pr, pn, hpcn)
clf
ccp_size=size(ccp);

if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) cpPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn) )
end
hpc_size=size(hpc);

% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) cpPlot(hpc, ccp, cea, of, of_min, of_max, of_step, pr, pn,  nearest(get(hObject,'Value'))) )
% end    


ax = gca;
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Cp [J/K]')
title({['Cp vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
ax.OuterPosition= [0 0.2 1 0.8];

i=1;
for h=hpc
    j=3;
    for g=pr
    cpm(i,j)=cea(i).output.eql.cp(pn,j);
    j=j+1;
    end
i=i+1;
end

i=1;
for h=hpc
    j=1;
        for g=[1 2]
    cpm(i,j)=cea(i).output.eql.cp(pn,g);
    j=j+1;
       end
i=i+1;
end

hold on


%*****
 if app.pressure_ratio_design_pressure.Value == false
                
i=3;
plot(hpc,cpm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

%combustion chamber
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,cpm(:,1),ofq1);

plot(hpc,cpm(:,1),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Combustion chamber-interpolated'])

%throat
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,cpm(:,2),ofq1);

plot(hpc,cpm(:,2),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Throat-interpolated'])


for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,cpm(:,i),ofq1);

plot(hpc,cpm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])

i=i+1;
end

 else
      i=3;
 
     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value

plot(hpc,cpm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

%combustion chamber
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,cpm(:,1),ofq1);

plot(hpc,cpm(:,1),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Combustion chamber-interpolated'])

%throat
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,cpm(:,2),ofq1);

plot(hpc,cpm(:,2),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Throat-interpolated'])

    
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,cpm(:,i),ofq1);

plot(hpc,cpm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])


             end
i=i+1;
     end
   
end
%***

lgd = legend;
legend('Location','eastoutside');
hold off

end
%% 7. γ Plot
function gammaPlot(hpc, ccp, cea, pr, pn, hpcn)
clf
ccp_size=size(ccp);

if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');

set(SliderCCP,'Callback',@(hObject,eventdata) gammaPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn) )
end 
 hpc_size=size(hpc);
 
%  if hpc_size(2) ~= 1
% 3
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) gammaPlot(hpc, ccp, cea, of, of_min, of_max, of_step, pr, pn,  nearest(get(hObject,'Value'))) )
%  end 
 
ax = gca;

ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('γ [-]')
title({['γ vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';

i=1;
for h=hpc
    j=3;
    for g=pr
    gammam(i,j)=cea(i).output.eql.gamma(pn,j);
    j=j+1;
    end
i=i+1;
end

i=1;
for h=hpc
    j=1;
       for g=[1 2]
    gammam(i,j)=cea(i).output.eql.gamma(pn,g);
    j=j+1;
       end
i=i+1;
end

hold on
ax.OuterPosition= [0 0.2 1 0.8];


%*****
 if app.pressure_ratio_design_pressure.Value == false
   
i=3;
plot(hpc,gammam(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

%combustion chamber
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,gammam(:,1),ofq1);

plot(hpc,gammam(:,1),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Combustion chamber-interpolated'])

%throat
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,gammam(:,2),ofq1);

plot(hpc,gammam(:,2),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Throat-interpolated'])

for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,gammam(:,i),ofq1);

plot(hpc,gammam(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])

i=i+1;
end
 
 else
      i=3;
 
     for h=pr
            if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
plot(hpc,gammam(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

%combustion chamber
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,gammam(:,1),ofq1);

plot(hpc,gammam(:,1),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Combustion chamber-interpolated'])

%throat
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,gammam(:,2),ofq1);

plot(hpc,gammam(:,2),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Throat-interpolated'])

    
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,gammam(:,i),ofq1);

plot(hpc,gammam(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])


             end
i=i+1;
 end
          
end
%***

lgd = legend;
legend('Location','eastoutside');
hold off

end


%% 8. Area Plot
function AreaPlot(hpc, ccp, cea, pr, pn, hpcn)
clf
ccp_size=size(ccp);

if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');

set(SliderCCP,'Callback',@(hObject,eventdata) AreaPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn) )
end 
 hpc_size=size(hpc);
 
%  if hpc_size(2) ~= 1
% 3
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) gammaPlot(hpc, ccp, cea, of, of_min, of_max, of_step, pr, pn,  nearest(get(hObject,'Value'))) )
%  end 
 i=1;
for h=hpc
    j=3;
    for g=pr
    Aream(i,j)=cea(i).output.eql.aeat(pn,j);
    j=j+1;
    end
i=i+1;
end


Table_nozzle(pn+1, 1) = hpc(end);
Table_nozzle(pn+1, 2) = ccp(pn);
Table_nozzle(pn+1, 5) = Aream(end,pn+2);
Table_nozzle(pn+1, 4) = (double(Aream(end,pn+2))^0.5)*double(Table_nozzle(pn+1, 3));
assignin('base','Nozzle_parameters',Table_nozzle);


ax = gca;

%ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Area ratio [-]')
title({['Area ratio vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';


hold on
ax.OuterPosition= [0 0.2 1 0.8];


%*****
 if app.pressure_ratio_design_pressure.Value == false
   i=3;
plot(hpc,Aream(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,Aream(:,i),ofq1);

plot(hpc,Aream(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])

i=i+1;
end

 else
      i=3;
 
     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value

 plot(hpc,Aream(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')   
ofq1=hpc_min:hpc_step/10:hpc_max;
of_i=spline(hpc,Aream(:,i),ofq1);

plot(hpc,Aream(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,of_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])


             end
i=i+1;
     end
          
end
%***

lgd = legend;
legend('Location','eastoutside');
hold off

end

%% E.1 Massflow rate of propellant Plot
function FlowratePlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav)

clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) FlowratePlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr
    mfrm(i,j)=1e3*Thrust/(grav*cea(i).output.eql.isp(pn,j));
    j=j+1;
    end
i=i+1;
end

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Mass flow rate [g/s]')
title({['Mass flow rate vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,mfrm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,mfrm(:,i),ofq1);

plot(hpc,mfrm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    
i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
 plot(hpc,mfrm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,mfrm(:,i),ofq1);

plot(hpc,mfrm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    

             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end


%% E.2 Loading factor Plot
function LoadingfactorPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) LoadingfactorPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav, d_cb) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr
    LFm(i,j)=(Thrust/(grav*cea(i).output.eql.isp(pn,j)))/(0.25*pi*(d_cb*1e-3)^2);  %Loading factor [kg/(s*m^2)] 
    j=j+1;
    end
i=i+1;
end

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Loading factor [kg/(s*m^2)]')
title({['Loading factor vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,LFm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,LFm(:,i),ofq1);

plot(hpc,LFm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    
i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
 plot(hpc,LFm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,LFm(:,i),ofq1);

plot(hpc,LFm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    

             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end

%% E.3 Throat Area Plot
function ThroatAreaPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) ThroatAreaPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav, d_cb) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr
        %A*=Thrust/(p_t*cf)
    Atm(i,j)=(Thrust/(1e5*ccp(pn)*cea(i).output.eql.cf(pn,j)));  %Throat area [m^2] 
    j=j+1;
    end
i=i+1;
end

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Throat Area [m^2]')
title({['Throat Area vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Atm(:,i),ofq1);

plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    
i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
 plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Atm(:,i),ofq1);

plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    

             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end


%% E.4 Throat Diameter Plot
function ThroatDiameterPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) ThroatDiameterPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav, d_cb) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr
        
    Dtm(i,j)=1e3*((Thrust/(1e5*ccp(pn)*cea(i).output.eql.cf(pn,j)))/(0.25*pi))^0.5;  %Throat Diamer [mm] 
    j=j+1;
    end
i=i+1;
end

Table_nozzle(pn+1, 1) = hpc(end);
Table_nozzle(pn+1, 2) = ccp(pn);
Table_nozzle(pn+1, 3) = Dtm(end,pn+2);
assignin('base','Nozzle_parameters',Table_nozzle);

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Throat Diameter [mm]')
title({['Throat Diameter vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,Dtm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Dtm(:,i),ofq1);

plot(hpc,Dtm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    
i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
 plot(hpc,Dtm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Dtm(:,i),ofq1);

plot(hpc,Dtm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    

             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end

%% E.5 Throat Area 2.0 Plot
function ThroatArea2Plot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) ThroatArea2Plot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav, d_cb) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr

        %A*=Thrust/(p_t*cf)
    Atm(i,j)=(Thrust/(grav*cea(i).output.eql.isp(pn,j)))/(cea(i).output.eql.density(pn,2)*cea(i).output.eql.sonvel(pn,2));  %Throat area [m^2] 
    j=j+1;
    end
i=i+1;
end

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Throat Area [m^2]')
title({['Throat Area vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Atm(:,i),ofq1);

plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    
i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
 plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Atm(:,i),ofq1);

plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    

             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end

%% E.6  Needle injector length Plot
function NeedleInjectorPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

%Variables for TEMPERATURE = 50 deg (source http://www.ethermo.us/Mars1124Vatemp!323.15!1~press!1000!4~model!1!1.htm)
nI= 3; %Numer of Needle Injectors [-]
pressuredrop = 0.15; %Pressure drop relative to pressure before nozzle [-]
dI = 0.108+0.019;  %Internal diameter of needle [mm]
AI = pi*(0.5*dI*1e-3)^2;  %Internal Area of Needle Injector [m^2]
miHP = 1.09266e-3; % Viscosity of hydrogen peroxide [Pa*s]
rhoHP = 1414.0801;  % Density of hydrogen peroxide [kg/m^3]
Cd = 0.95; %s 𝐶𝑑 = 𝑚_𝑒𝑥𝑝̇/m_𝑖𝑑𝑒𝑎l  [-]


clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) NeedleInjectorPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav, d_cb) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr

    mfrm(i,j)=Thrust/(grav*cea(i).output.eql.isp(pn,j));  %Nass flow rate [kg/s]
       dpI = ccp(pn)*pressuredrop*1e5; % Pressure drop in one injector [Pa]
    Atm(i,j)=1e3*dpI/((1/nI)*mfrm(i,j)*((8*pi*miHP)/rhoHP)*(1/(Cd*AI^2)));  %Minimum Needle Injector length [mm] 
    j=j+1;
    end
i=i+1;
end

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Injectr length [mm]')
title({['Minimum injector length (dp=',num2str(pressuredrop*100),'%) vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Atm(:,i),ofq1);

plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    
i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
 plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Atm(:,i),ofq1);

plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    

             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end

%% E.7  Needle injector pressure drop wiPlot
function NeedleInjectorpressuredropPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

%Variables for TEMPERATURE = 50 deg (source http://www.ethermo.us/Mars1124Vatemp!323.15!1~press!1000!4~model!1!1.htm)

needle_length = [4.54 6 7]; %Lengths of needle injectors [mm]
dI = 0.108+0.019;  %Internal diameter of needle [mm]
AI = pi*(0.5*dI*1e-3)^2;  %Internal Area of Needle Injector [m^2]
miHP = 1.09266e-3; % Viscosity of hydrogen peroxide [Pa*s]
rhoHP = 1414.0801;  % Density of hydrogen peroxide [kg/m^3]
Cd = 0.95; %s 𝐶𝑑 = 𝑚_𝑒𝑥𝑝̇/m_𝑖𝑑𝑒𝑎l  [-]


clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) NeedleInjectorpressuredropPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav, d_cb) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end 
    l=1;
        for k=needle_length
i=1;
for h=hpc
    j=3;
    for g=pr
   mfrm(i,j)=Thrust/(grav*cea(i).output.eql.isp(pn,j));  %Mass flow rate [kg/s]
       
    Atm(i,j,l)=100*((mfrm(i,j)/size(needle_length,2))*(8*pi*miHP/rhoHP)*(needle_length(l)*1e-3/(Cd*AI^2)))/(ccp(pn)*1e5);  %Needle Injector Pressure drop [%] 
     % Atm(i,j,l)=(needle_length(l)*1e-3)*((1/3)*mfrm(i,j)*((8*pi*miHP)/rhoHP)*(1/(Cd*AI^2)));
       
    j=j+1;
    end
i=i+1;
end
        l=l+1;
        end
ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Pressure drop [%]')
title({['Pressure drop vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,Atm(:,i,1),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
     l=1;
        for k=needle_length
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Atm(:,i,l),ofq1);

plot(hpc,Atm(:,i,l),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated and L=',num2str(needle_length(l)),'mm'])
      l=l+1;
        end
i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
                 
 plot(hpc,Atm(:,i,1),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
                l=1;
                 for k=needle_length
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Atm(:,i,l),ofq1);

plot(hpc,Atm(:,i,l),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolatedand L=',num2str(needle_length(l)),'mm'])
                   l=l+1;
                  end

             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end

%% E.8  Needle injector length Plot
            function NeedleInjectorpressuredrop2Plot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb, N1L, N2L, N3L, n)

%Variables for TEMPERATURE = 50 deg (source http://www.ethermo.us/Mars1124Vatemp!323.15!1~press!1000!4~model!1!1.htm)
needle_length = [N1L N2L N3L]; %Lengths of needle injectors [mm]
dI = 0.108;  %Internal diameter of needle [mm]
AI = pi*(0.5*dI*1e-3)^2;  %Internal Area of Needle Injector [m^2]
miHP = 1.09266e-3; % Viscosity of hydrogen peroxide [Pa*s]
rhoHP = 1414.0801;  % Density of hydrogen peroxide [kg/m^3]
Cd = 0.95; %s 𝐶𝑑 = 𝑚_𝑒𝑥𝑝̇/m_𝑖𝑑𝑒𝑎l  [-]


clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) NeedleInjectorpressuredrop2Plot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav, d_cb, N1L, N2L, N3L, n) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr

    mfrm(i,j)=Thrust/(grav*cea(i).output.eql.isp(pn,j));  %Mass flow rate [kg/s]
      A_matrix = zeros(size(needle_length,2)+1);
      A_matrix(1, 1:(end-1))=1;
      A_matrix(2:end, end)=-1;
      l=1;
      for h=needle_length
        A_matrix(l+1, l)=(8*pi*miHP/rhoHP)*(needle_length(l)*1e-3/(Cd*AI^2));
      l=l+1;
      end
      
    B_matrix=zeros(size(needle_length,2)+1,1);
    B_matrix(1,1) = mfrm(i,j);

X=linsolve(A_matrix, B_matrix);

    Atm(i,j)=100*X(end)/(ccp(pn)*1e5);  %Needle injector pressure drop [%] 
   
    j=j+1;
    end
i=i+1;
end
Table_pressure_drop(n, 1)=N1L;
Table_pressure_drop(n, 2)=N2L;
Table_pressure_drop(n, 3)=N3L;
Table_pressure_drop(n, 4)=ccp(1);
Table_pressure_drop(n, 5)=hpc(end);
Table_pressure_drop(n, 6)=Atm(end,3);

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Pressure drop [%]')
title({['Pressure drop vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar'] 
    ['injector lengths: ',num2str(needle_length)]})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Atm(:,i),ofq1);

plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    
i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
 plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Atm(:,i),ofq1);

plot(hpc,Atm(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
    

             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end


%% E.9  Wall thickness Plot
function WallThicknessPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

%Variables 
n_hc=2.5; %Safety factor [-]

a_nc=45; %Nozzle Converging half angle [deg]
a_nd=15; %Nozzle divergen half angle [deg]

D_t=1; %Throat diameter [mm]
D_ne=2; %Nozzle exit diameter [mm]
D_tc=11; %Top cover inner diameter

S_hc=35; %Max stress Reaction chamber [MPa]
S_nc=35; %Max stress Nozzle Converging [MPa]
S_t=35; %Max stress Throat [MPa]
S_nd=35; %Max stress nozzle divergent [MPa]
S_tc=180; %Max stress Top cover [MPa]


clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) WallThicknessPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav, d_cb) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr

   % mfrm(i,j)=Thrust/(grav*cea(i).output.eql.isp(pn,j));  %Mass flow rate [kg/s]

    Tc(i,j)=1e-3*n_hc*ccp(pn)*1e5*app.Catalyst_bed_diameter_Spinner.Value*1e3/(2*S_hc*1e6);%  %Cylinder-reaction chamber Wall thickness [mm] Only hoop stress
    Tnc_up(i,j)=1e-3*n_hc*ccp(pn)*1e5*app.Catalyst_bed_diameter_Spinner.Value*1e3/(2*S_nc*1e6*cosd(a_nc));%  %Cone-Nozzle Converging Wall thickness up [mm] Only hoop stress
    Tnc_down(i,j)=1e-3*n_hc*ccp(pn)*1e5*D_t*1e3/(2*S_nc*1e6*cosd(a_nc));%  %Cone-Nozzle Converging Wall thickness down [mm] Only hoop stress
    Tt(i,j)=1e-3*n_hc*ccp(pn)*1e5*D_t*1e3/(2*S_t*1e6);%  %Cylinder-Throat Wall thickness [mm] Only hoop stress
    Tnd_up(i,j)=1e-3*n_hc*ccp(pn)*1e5*D_t*1e3/(2*S_nd*1e6*cosd(a_nd));%  %Cone-Nozzle divergent up Wall thickness [mm] Only hoop stress
    Tnd_down(i,j)=1e-3*n_hc*ccp(pn)*1e5*D_ne*1e3/(2*S_nd*1e6*cosd(a_nd));%  %Cone-Nozzle divergent exit Wall thickness [mm] Only hoop stress
    Ttc(i,j)=1e-3*(n_hc*ccp(pn)*1e5*1.238*(0.5*D_tc)^2/S_tc)^0.5; %Top cover thickness [mm]
    
    j=j+1;
    end
i=i+1;
end


Table_wall_thickness(pn+1, 1) = hpc(2);
Table_wall_thickness(pn+1, 2) = ccp(pn);
Table_wall_thickness(pn+1, 3) = Tc(2,pn+2);
Table_wall_thickness(pn+1, 4) = Tnc_up(2,pn+2);
Table_wall_thickness(pn+1, 5) = Tnc_down(2,pn+2);
Table_wall_thickness(pn+1, 6) = Tt(2,pn+2);
Table_wall_thickness(pn+1, 7) = Tnd_up(2,pn+2);
Table_wall_thickness(pn+1, 8) = Tnd_down(2,pn+2);
Table_wall_thickness(pn+1, 9) = Ttc(2,pn+2);
assignin('base','Table_wall_thickness',Table_wall_thickness);


ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Wall thickness [mm]')
title({['Wall thickness vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar'] 
    ['Reaction chamber diameter: ',num2str(app.Catalyst_bed_diameter_Spinner.Value),' mm']
    ['Nozzle convergent part half angle: ',num2str(a_nc), 'deg']
    ['Throat diameter: ',num2str(D_t), 'mm']
    ['Nozzle divergent part half angle: ',num2str(a_nd) ,'deg']
    ['Safety factor: ',num2str(n_hc)]})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tc(:,i),ofq1);

plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Reaction chamber pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
  
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tnc_up(:,i),ofq1);

plot(hpc,Tnc_up(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Nozzle Convergent, up pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])

ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tnc_down(:,i),ofq1);

plot(hpc,Tnc_down(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Nozzle Convergent down pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])


ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tt(:,i),ofq1);

plot(hpc,Tt(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Throat pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
  
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tnd_up(:,i),ofq1);

plot(hpc,Tnd_up(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Nozzle divergent up pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])

ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tnd_down(:,i),ofq1);

plot(hpc,Tnd_down(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Nozzle divergent exit pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])


i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
 plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tc(:,i),ofq1);

plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Reaction chamber pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])


ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tnc_up(:,i),ofq1);

plot(hpc,Tnc_up(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Nozzle Convergent up pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
 
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tnc_down(:,i),ofq1);

plot(hpc,Tnc_down(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Nozzle Convergent down pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
 
  
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tt(:,i),ofq1);

plot(hpc,Tt(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Throat pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
  
  
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tnd_up(:,i),ofq1);

plot(hpc,Tnd_up(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Nozzle divergent up pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])

ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tnd_down(:,i),ofq1);

plot(hpc,Tnd_down(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Nozzle divergent exit pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])


             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end


%% E.10  Heat Separator Plot
function HeatSeparatorPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

%Variables 
k_HS = 21; %Thermal_conductivity [W/(m*K)] 
C_p = 2619; %Heat capcity [J/(kg*K)]
T_supp = 21; %Temperature of propellant supply [deg]
T_max = 40; %Max temperature of propellant [deg]
T_2 = 300; %Assumed temperature of hot side [deg]
A_HS = 18.064*1e-6; %Cross section area [m^2]

A_radiatiom = 0.00150639368; %radiation area [m^2]
sbc = 5.67037321212121*1e-8;	%Stefan–Boltzmann constant [W·m^−2·K^−4]
em =1; %emissivity factor [-]
Temp_radiation = 105+273.15; %Temperature assumed for radiation [K]

clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) HeatSeparatorPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav, d_cb) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr

    mfrm(i,j)=Thrust/(grav*cea(i).output.eql.isp(pn,j));  %Mass flow rate [kg/s]

   % Tc(i,j)=1e3*(k_HS*A_HS*(T_2-T_max))/(C_p*(T_max-T_supp)*mfrm(i,j));%  %Length of heat separator [mm] 
     Tc(i,j)=1e3*(k_HS*A_HS*(273.15+T_2-Temp_radiation))/(em*sbc*A_radiatiom*Temp_radiation^4);%  %Length of heat separator [mm] 
    
    j=j+1;
    end
i=i+1;
end

ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Length of heat separator [mm]')
title({['Length of heat separator vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar'] 
    ['Cross section area: ',num2str(A_HS*1e6),' mm^2']
    ['Propellant supply temperature: ',num2str(T_supp), 'deg']
    ['Max propellant temperature: ',num2str(T_max), 'deg']
    ['Assumed hot side temperature: ',num2str(T_2), 'deg']
    ['Heat capacity of propellant: ',num2str(C_p) ,'J/(kg*k)']
    ['Thermal conductivity: ',num2str(k_HS),'W/(m*K)']})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tc(:,i),ofq1);

plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])


i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
 plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tc(:,i),ofq1);

plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])


             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end

%% E.11  Propellant Feed tube Plot
function PFTdpPlot(hpc, ccp, cea, pr, pn, hpcn, Thrust, grav, d_cb)

%Variables 
miHP=1.25e-3; %Dynamic visosity of propellant [Pa*s]
rhoHP = 1431.1;  % Density of hydrogen peroxide [kg/m^3]
needle_length=21e-3; %Length of propellant feed tube [m]
Needle_diameter=0.26e-3; %internal diameter of propellant feed tube [m]
AI=pi*0.25*Needle_diameter^2; %internal cross section area of propellant feed tube [m^2]


clf                         %Clear figure after slider movement
ccp_size=size(ccp);
if ccp_size(2) ~= 1
SliderCCP = uicontrol('Style','slider','Position',[81,50,419,20],...
             'value',pn, 'min',1, 'max',ccp_size(2),'SliderStep', [1/(ccp_size(2)-1) 1/(ccp_size(2)-1)]);
         
 TextCCP = uicontrol('Style','text','Position',[30,50,50,20],...
                'String','CCP [bar]');
 
set(SliderCCP,'Callback',@(hObject,eventdata) PFTdpPlot(hpc, ccp, cea, pr,  nearest(get(hObject,'Value')), hpcn, Thrust, grav, d_cb) )
end 

hpc_size=size(hpc);
% if hpc_size(2) ~= 1
%  SliderHPC = uicontrol('Style','slider','Position',[81,15,419,20],...
%              'value',hpcn, 'min',1, 'max',hpc_size(2),'SliderStep', [1/(hpc_size(2)-1) 1/(hpc_size(2)-1)]);
%          
%  TextHPC = uicontrol('Style','text','Position',[30,15,50,20],...
%                 'String','HPC [%]');         
%          
% set(SliderHPC,'Callback',@(hObject,eventdata) ispPlot(hpc, ccp, cea, pr, pn,  nearest(get(hObject,'Value'))) )
%         
% end
i=1;
for h=hpc
    j=3;
    for g=pr

    mfrm(i,j)=Thrust/(grav*cea(i).output.eql.isp(pn,j));  %Mass flow rate [kg/s]

    Tc(i,j)=1e-5*mfrm(i,j)*((8*pi*miHP)/(rhoHP))*((needle_length)/(AI^2));  %Pressure drop in Propellant feed tube [bar]
    j=j+1;
    end
i=i+1;
end


Table_PFT_dp(pn+1, 1) = hpc(end);
Table_PFT_dp(pn+1, 2) = ccp(pn);
Table_PFT_dp(pn+1, 3) = Tc(end,pn+2);
assignin('base','Table_Propellant_feed_tube_pressure_drop',Table_PFT_dp);


ax = gca;
ax.OuterPosition= [0 0.2 1 0.8];
ax.XAxisLocation = 'origin';
%ax.XLim=[0 (Lg2+Ls+Lg1)];
%yticks(-2:1:4.5);
%ax.YLim=[-2 4.5];
xlabel([app.Reactant_1_Name.Value, ' concentration [%]'])
ylabel('Pressure drop [bar]')
title({['Propellant feed tube Pressure drop vs ',app.Reactant_1_Name.Value, ' concentration']
    ['and for Pressure before nozzle=',num2str(ccp(pn)),' bar']})
ax.XColor = 'black';
hold on
%*****
 if app.pressure_ratio_design_pressure.Value == false
                
    i=3;
  plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')

 for h=pr
    
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tc(:,i),ofq1);

plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Reaction chamber pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])
  
i=i+1;
    end
 else
      i=3;
  

     for h=pr
             if (ccp(pn)/pr(i-2))==app.Design_pressure.Value
 plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','DisplayName','Calculated points')
ofq1=hpc_min:hpc_step/10:hpc_max;
isp_i=spline(hpc,Tc(:,i),ofq1);

plot(hpc,Tc(:,i),'o','MarkerEdgeColor','black','HandleVisibility','off')
plot(ofq1,isp_i,'color',rand(1,3), 'LineWidth', 1,'DisplayName',['Reaction chamber pa= ',num2str(ccp(pn)/pr(i-2)),'bar interpolated'])

             end
      i=i+1;
     end
          
end
%***
lgd = legend;
legend('Location','eastoutside');
hold off

end

        end

        % Value changed function: Reactant_3_on_off
        function Reactant_3_on_offValueChanged(app, event)
            if app.Reactant_3_on_off.Value == false
                app.Reactant_3_Name.Enable = 'off';
                app.Reactant_3_concentration.Enable = 'off';
                app.OxidaizerconcentrationLabel.Enable = 'off';
                app.Reactan3Label.Enable = 'off';

            
            else
            app.Reactant_3_Name.Enable = 'on';
            app.Reactant_3_concentration.Enable = 'on';
            app.OxidaizerconcentrationLabel.Enable = 'on';
            app.Reactan3Label.Enable = 'on';
             end
            
        end

        % Callback function
        function Oxidizer_concentration_MaxValueChanged(app, event)
            value = app.Oxidizer_concentration_Max.Value;
            
        end

        % Value changed function: pressure_ratio_design_pressure
        function pressure_ratio_design_pressureValueChanged(app, event)
            value = app.pressure_ratio_design_pressure.Value;
           if app.pressure_ratio_design_pressure.Value == false
                app.Design_pressure.Enable = 'off';
                app.OxidaizerconcentrationLabel_2.Enable = 'off';
                app.Pressure_ratio_Max.Enable = 'on';
                app.Pressure_ratio_min.Enable = 'on';
                app.Pressure_ratio_step.Enable = 'on';
                app.PressureratioPressurebeforenozzleexhauspressureLabel.Enable = 'on';
                
            
            else
                app.Design_pressure.Enable = 'on';
                app.OxidaizerconcentrationLabel_2.Enable = 'on';
                app.Pressure_ratio_Max.Enable = 'off';
                app.Pressure_ratio_min.Enable = 'off';
                app.Pressure_ratio_step.Enable = 'off';
                app.PressureratioPressurebeforenozzleexhauspressureLabel.Enable = 'off';
                
             end
        end

        % Value changed function: Design_pressure
        function Design_pressureValueChanged(app, event)
            value = app.Design_pressure.Value;
            
        end

        % Value changed function: MonopropelantenginecalculationsONOFF
        function MonopropelantenginecalculationsONOFFValueChanged(app, event)
            value = app.MonopropelantenginecalculationsONOFF.Value;
             if app.MonopropelantenginecalculationsONOFF.Value == false
                app.Engine_thrust_Spinner.Enable = 'off';
                app.EnginethrustNSpinnerLabel.Enable = 'off';
                app.Catalyst_bed_diameter_Spinner.Enable = 'off';
                app.CatalystbeddiametermmSpinnerLabel.Enable = 'off';
                app.Gravitational_acceleration_Spinner.Enable = 'off';
                app.Gravitationalaccelerationms2Label.Enable = 'off';
            else
                app.Engine_thrust_Spinner.Enable = 'on';
                app.EnginethrustNSpinnerLabel.Enable = 'on';
                app.Catalyst_bed_diameter_Spinner.Enable = 'on';
                app.CatalystbeddiametermmSpinnerLabel.Enable = 'on';
                app.Gravitational_acceleration_Spinner.Enable = 'on';
                app.Gravitationalaccelerationms2Label.Enable = 'on';
             end
        end

        % Button pushed function: CloseallplotsButton
        function close_all(app, event)
            close all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CEAMichalSkibaUIFigure = uifigure('Visible', 'off');
            app.CEAMichalSkibaUIFigure.Name = 'CEA Michal Skiba';
            app.StartButton = uibutton(app.CEAMichalSkibaUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CCT_Plot = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.CCT_Plot.Text = 'Plot 1-Temperature before nozzle vs Rectant 1 concentration';
            app.CCT_Plot.Value = true;
            app.isp_Plot = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.isp_Plot.Text = 'Plot 2-Specific impulse vs Rectant 1 concentration';
            app.isp_Plot.Value = true;
            app.Cf_Plot = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.Cf_Plot.Text = 'Plot 4-Cf vs Rectant 1 concentration';
            app.Cf_Plot.Value = true;
            app.Cstar_Plot = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.Cstar_Plot.Text = 'Plot 5-C* vs Rectant 1 concentration';
            app.Cstar_Plot.Value = true;
            app.OxidaizerconcentrationLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.OxidaizerconcentrationLabel.Enable = 'off';
            app.OxidaizerconcentrationLabel.Text = 'Reactant 3 concentration to reactant 2 [%]';
            app.Reactant_3_concentration = uispinner(app.CEAMichalSkibaUIFigure);
            app.Reactant_3_concentration.Limits = [0 100];
            app.Reactant_3_concentration.Enable = 'off';
            app.Reactant_3_concentration.Value = 10;
            app.Reactant1Label = uilabel(app.CEAMichalSkibaUIFigure);
            app.Reactant1Label.Text = 'Reactant 1';
            app.Reactant_1_Name = uieditfield(app.CEAMichalSkibaUIFigure, 'text');
            app.Reactant_1_Name.Value = 'H2O2(L)';
            app.Reactant2Label = uilabel(app.CEAMichalSkibaUIFigure);
            app.Reactant2Label.Text = 'Reactant 2';
            app.Reactant_2_Name = uieditfield(app.CEAMichalSkibaUIFigure, 'text');
            app.Reactant_2_Name.Value = 'C2H5OH(L)';
            app.Reactan3Label = uilabel(app.CEAMichalSkibaUIFigure);
            app.Reactan3Label.Enable = 'off';
            app.Reactan3Label.Text = 'Reactant 3';
            app.Reactant_3_Name = uieditfield(app.CEAMichalSkibaUIFigure, 'text');
            app.Reactant_3_Name.Enable = 'off';
            app.Reactant_3_Name.Value = 'Jet-A(L)';
            app.Cp_Plot = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.Cp_Plot.Text = 'Plot 6-Cp vs Rectant 1 concentration';
            app.Cp_Plot.Value = true;
            app.Gamma_Plot = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.Gamma_Plot.Text = 'Plot 7-γ vs Rectant 1 concentration';
            app.Gamma_Plot.Value = true;
            app.minimumLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.minimumLabel.Text = 'minimum';
            app.StepsizeLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.StepsizeLabel.Text = 'Step size';
            app.MaximumLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.MaximumLabel.Text = 'Maximum';
            app.PressurebeforenozzlebarLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.PressurebeforenozzlebarLabel.Text = 'Pressure before nozzle [bar]';
            app.CCP_min = uispinner(app.CEAMichalSkibaUIFigure);
            app.CCP_min.Limits = [0 Inf];
            app.CCP_min.Value = 22;
            app.CCP_step = uispinner(app.CEAMichalSkibaUIFigure);
            app.CCP_step.Limits = [0.1 Inf];
            app.CCP_step.Value = 1;
            app.CCP_Max = uispinner(app.CEAMichalSkibaUIFigure);
            app.CCP_Max.Limits = [0 Inf];
            app.CCP_Max.Value = 27;
            app.Reactant1torestLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.Reactant1torestLabel.Text = 'Reactant 1 to rest [%]';
            app.Reactant_1_to_rest_min = uispinner(app.CEAMichalSkibaUIFigure);
            app.Reactant_1_to_rest_min.Limits = [0 100];
            app.Reactant_1_to_rest_min.Value = 70;
            app.Reactant_1_to_rest_step = uispinner(app.CEAMichalSkibaUIFigure);
            app.Reactant_1_to_rest_step.Limits = [0.1 Inf];
            app.Reactant_1_to_rest_step.Value = 1;
            app.Reactant_1_to_rest_Max = uispinner(app.CEAMichalSkibaUIFigure);
            app.Reactant_1_to_rest_Max.Limits = [0 Inf];
            app.Reactant_1_to_rest_Max.Value = 85;
            app.minimumLabel_2 = uilabel(app.CEAMichalSkibaUIFigure);
            app.minimumLabel_2.Text = 'minimum';
            app.NumberofstepsLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.NumberofstepsLabel.Text = 'Number of steps';
            app.MaximumLabel_2 = uilabel(app.CEAMichalSkibaUIFigure);
            app.MaximumLabel_2.Text = 'Maximum';
            app.Pressure_ratio_step = uispinner(app.CEAMichalSkibaUIFigure);
            app.Pressure_ratio_step.Limits = [1 Inf];
            app.Pressure_ratio_step.ValueDisplayFormat = '%.0f';
            app.Pressure_ratio_step.Value = 10;
            app.Pressure_ratio_Max = uispinner(app.CEAMichalSkibaUIFigure);
            app.Pressure_ratio_Max.Limits = [1.01 Inf];
            app.Pressure_ratio_Max.Value = 100;
            app.PressureratioPressurebeforenozzleexhauspressureLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.PressureratioPressurebeforenozzleexhauspressureLabel.Text = 'Pressure ratio (Pressure before nozzle/exhaus pressure) [-]';
            app.Pressure_ratio_min = uispinner(app.CEAMichalSkibaUIFigure);
            app.Pressure_ratio_min.Limits = [1.01 Inf];
            app.Pressure_ratio_min.Value = 1.01;
            app.Reactant_3_on_off = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.Reactant_3_on_off.ValueChangedFcn = createCallbackFcn(app, @Reactant_3_on_offValueChanged, true);
            app.Reactant_3_on_off.Text = '';
            app.Reactant3OnOffLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.Reactant3OnOffLabel.Text = 'Reactant 3 On/Off';
            app.ChooseplotstobeshownLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.ChooseplotstobeshownLabel.Text = 'Choose plots to be shown ';
            app.ClickstarttostartcalculationsLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.ClickstarttostartcalculationsLabel.Text = 'Click start to start calculations';
            app.FuelTemperatureKLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.FuelTemperatureKLabel.Text = 'Reactant 1  Temperature [K]';
            app.Reactant_1_Temperature = uispinner(app.CEAMichalSkibaUIFigure);
            app.Reactant_1_Temperature.Limits = [0 Inf];
            app.Reactant_1_Temperature.Value = 300;
            app.Reactant23TemperatureKLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.Reactant23TemperatureKLabel.Text = 'Reactant 2 & 3 Temperature [K]';
            app.Reactant_2_3_Temperature = uispinner(app.CEAMichalSkibaUIFigure);
            app.Reactant_2_3_Temperature.Limits = [0 Inf];
            app.Reactant_2_3_Temperature.Value = 300;
            app.isp_vac_Plot = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.isp_vac_Plot.Text = 'Plot 3-Vacuum Specific impulse vs Rectant 1 concentration';
            app.isp_vac_Plot.Value = true;
            app.pressure_ratio_design_pressure = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.pressure_ratio_design_pressure.ValueChangedFcn = createCallbackFcn(app, @pressure_ratio_design_pressureValueChanged, true);
            app.pressure_ratio_design_pressure.Text = '';
            app.pressure_ratio_design_pressure.Value = true;
            app.pressure_ratio_design_pressureLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.pressure_ratio_design_pressureLabel.Text = 'Pressure ratio/Desin altitude pressure';
            app.OxidaizerconcentrationLabel_2 = uilabel(app.CEAMichalSkibaUIFigure);
            app.OxidaizerconcentrationLabel_2.Enable = 'off';
            app.OxidaizerconcentrationLabel_2.Text = 'Design altitude pressure [Bar]';
            app.Design_pressure = uispinner(app.CEAMichalSkibaUIFigure);
            app.Design_pressure.Limits = [0 Inf];
            app.Design_pressure.ValueChangedFcn = createCallbackFcn(app, @Design_pressureValueChanged, true);
            app.Design_pressure.Enable = 'off';
            app.Design_pressure.Value = 1;
            app.Area_ratio_Plot = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.Area_ratio_Plot.Text = 'Plot 8-Area ratio ratio vs Rectant 1 concentration';
            app.Area_ratio_Plot.Value = true;
            app.MonopropelantenginecalculationsONOFF = uicheckbox(app.CEAMichalSkibaUIFigure);
            app.MonopropelantenginecalculationsONOFF.ValueChangedFcn = createCallbackFcn(app, @MonopropelantenginecalculationsONOFFValueChanged, true);
            app.MonopropelantenginecalculationsONOFF.Text = 'Monopropelant engine calculations ON/OFF';
            app.MonopropelantenginecalculationsLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.MonopropelantenginecalculationsLabel.Text = 'Monopropelant engine calculations';
            app.EnginethrustNSpinnerLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.EnginethrustNSpinnerLabel.Enable = 'off';
            app.EnginethrustNSpinnerLabel.Text = 'Engine thrust [N]';
            app.Engine_thrust_Spinner = uispinner(app.CEAMichalSkibaUIFigure);
            app.Engine_thrust_Spinner.Limits = [0 Inf];
            app.Engine_thrust_Spinner.Enable = 'off';
            app.Engine_thrust_Spinner.Value = 1;
            app.CatalystbeddiametermmSpinnerLabel = uilabel(app.CEAMichalSkibaUIFigure);
            app.CatalystbeddiametermmSpinnerLabel.Enable = 'off';
            app.CatalystbeddiametermmSpinnerLabel.Text = 'Catalyst bed diameter [mm]';
            app.Catalyst_bed_diameter_Spinner = uispinner(app.CEAMichalSkibaUIFigure);
            app.Catalyst_bed_diameter_Spinner.Limits = [0 Inf];
            app.Catalyst_bed_diameter_Spinner.Enable = 'off';
            app.Catalyst_bed_diameter_Spinner.Value = 7;
            app.Gravitationalaccelerationms2Label = uilabel(app.CEAMichalSkibaUIFigure);
            app.Gravitationalaccelerationms2Label.Enable = 'off';
            app.Gravitationalaccelerationms2Label.Text = 'Gravitational acceleration [m/s^2]';
            app.Gravitational_acceleration_Spinner = uispinner(app.CEAMichalSkibaUIFigure);
            app.Gravitational_acceleration_Spinner.Limits = [0 Inf];
            app.Gravitational_acceleration_Spinner.Enable = 'off';
            app.Gravitational_acceleration_Spinner.Value = 9.80665;
            app.CloseallplotsButton = uibutton(app.CEAMichalSkibaUIFigure, 'push');
            app.CloseallplotsButton.ButtonPushedFcn = createCallbackFcn(app, @close_all, true);
            app.CloseallplotsButton.Text = {'Close all plots'; ''};
            % Show the figure after all components are created
            app.CEAMichalSkibaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CEAM_app_eng_th_Michal_Skiba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CEAMichalSkibaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CEAMichalSkibaUIFigure)
        end
    end
end